classdef ExperimentDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExperimentDesignerUIFigure      matlab.ui.Figure
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        EditasCopyMenu                  matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        MeasurementDesignerPanel        matlab.ui.container.Panel
        ExperimentLabel                 matlab.ui.control.Label
        ExperimentEditField             matlab.ui.control.EditField
        SubjectCodeEditFieldLabel       matlab.ui.control.Label
        SubjectCodeEditField            matlab.ui.control.EditField
        SessionEditFieldLabel           matlab.ui.control.Label
        SessionEditField                matlab.ui.control.EditField
        MeasurementEditFieldLabel       matlab.ui.control.Label
        MeasurementEditField            matlab.ui.control.EditField
        MethodsListBox                  matlab.ui.control.ListBox
        MethodsLabel                    matlab.ui.control.Label
        AddselectedmethodtoprotocolButton  matlab.ui.control.Button
        ProtocolPanel                   matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        RemovefromprotocolButton        matlab.ui.control.Button
        ClearallButton                  matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ProtocolParametersPanel         matlab.ui.container.Panel
        NoMethodisselectedfromProtocolLabel  matlab.ui.control.Label
        InputOutputCurveMEPParametersPanel  matlab.ui.container.Panel
        MinimumStimulationIntensityEditFieldLabel  matlab.ui.control.Label
        MinimumStimulationIntensityEditField  matlab.ui.control.NumericEditField
        StimulationIntensityUnitsLabel  matlab.ui.control.Label
        MaximumStimulationIntensityEditFieldLabel  matlab.ui.control.Label
        MaximumStimulationIntensityEditField  matlab.ui.control.NumericEditField
        StepsizeofStimulationIntensitiesEditFieldLabel  matlab.ui.control.Label
        StepsizeofStimulationIntensitiesEditField  matlab.ui.control.NumericEditField
        NooftrialsperStimulationIntensityEditFieldLabel  matlab.ui.control.Label
        NooftrialsperStimulationIntensityEditField  matlab.ui.control.NumericEditField
        MinimumIntertrialIntervalMinITIEditFieldLabel  matlab.ui.control.Label
        MinimumIntertrialIntervalMinITIEditField  matlab.ui.control.NumericEditField
        MaximumIntertrialIntervalMaxITIEditFieldLabel  matlab.ui.control.Label
        MaximumIntertrialIntervalMaxITIEditField  matlab.ui.control.NumericEditField
        StimulationParadigmDropDownLabel  matlab.ui.control.Label
        StimulationParadigmDropDown     matlab.ui.control.DropDown
        LiveMEPMeasurementPlotsCheckBox  matlab.ui.control.CheckBox
        LiveInputOutputCurvePlotsCheckBox  matlab.ui.control.CheckBox
        ButtonGroup                     matlab.ui.container.ButtonGroup
        MSOButton                       matlab.ui.control.RadioButton
        RMTButton                       matlab.ui.control.RadioButton
        RMTLabel                        matlab.ui.control.Label
        RMTLabel_2                      matlab.ui.control.Label
        RMTLabel_3                      matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        StimIntenUnitsPanel             matlab.ui.container.Panel
        MSOLabel_4                      matlab.ui.control.Label
        MSOLabel_5                      matlab.ui.control.Label
        MSOLabel_6                      matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
    end


    properties (Access = public)
        AddMethod % Description
        Save
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.NewMenu.BackgroundColor = [0.9412 0.9412 0.9412];
              app.AddMethod=0; %Add Method Button Counter
              
              
              %TODO----Add if clauses here for apperance of the parameter panels;
              
              if (strcmp(app.ListBox.Value,'Input Output Curve (MEP)'))
                  app.InputOutputCurveMEPParametersPanel.Visible='on';
              else
                  app.InputOutputCurveMEPParametersPanel.Visible='off';
              end
              
              
        end

        % Button pushed function: AddselectedmethodtoprotocolButton
        function AddselectedmethodtoprotocolButtonPushed(app, event)
            app.AddMethod=app.AddMethod+1;
            app.ListBox.Items(app.AddMethod)={app.MethodsListBox.Value};
        end

        % Button pushed function: RemovefromprotocolButton
        function RemovefromprotocolButtonPushed(app, event)
            app.ListBox.ItemsData=1:numel(app.ListBox.Items);
            slctd_index=app.ListBox.Value;
            app.ListBox.ItemsData=[];
            app.ListBox.Items(slctd_index)=[];
            app.AddMethod=app.AddMethod-1;
            app.ListBox.Value={};
            if (strcmp(app.ListBox.Value,'Input Output Curve (MEP)'))
                  app.InputOutputCurveMEPParametersPanel.Visible='on';
              else
                  app.InputOutputCurveMEPParametersPanel.Visible='off';
              end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ButtonGroup.SelectedObject;

% TODO:D ADD app.StimItenUnitsPanel.Visible='off' command in start of value change function 
            if (strcmp(app.ButtonGroup.SelectedObject.Text,'% RMT'))
                app.StimIntenUnitsPanel.Visible='off';
            else
                app.StimIntenUnitsPanel.Visible='on';
            end
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
           app.ListBox.Value
           if (strcmp(app.ListBox.Value,'Input Output Curve (MEP)'))
               app.InputOutputCurveMEPParametersPanel.Visible='on';
           else
               app.InputOutputCurveMEPParametersPanel.Visible='off';
           end
          
            
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.Save.Experiment=app.ExperimentEditField.Value;
            app.Save.SubjectCode=app.SubjectCodeEditField.Value;
            app.Save.Session=app.SessionEditField.Value;
            app.Save.Measurement=app.MeasurementEditField.Value;
            app.Save.Protocol_list=app.ListBox.Items;
            app.Save.AddMethod=app.AddMethod;
            
ioo=app.Save
save('pqfile.mat','ioo')

           
%            save(a)
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            file = uigetfile
            load pqfile.mat
             app.ExperimentEditField.Value=ioo.Experiment
             app.SubjectCodeEditField.Value=ioo.SubjectCode;
            app.SessionEditField.Value=ioo.Session;
            app.MeasurementEditField.Value=ioo.Measurement;
            app.ListBox.Items=ioo.Protocol_list;
            app.AddMethod=ioo.AddMethod;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExperimentDesignerUIFigure = uifigure;
            app.ExperimentDesignerUIFigure.Name = 'Experiment Designer';
            app.NewMenu = uimenu(app.ExperimentDesignerUIFigure);
            app.NewMenu.Text = 'New   ';
            app.OpenMenu = uimenu(app.ExperimentDesignerUIFigure);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open   ';
            app.EditasCopyMenu = uimenu(app.ExperimentDesignerUIFigure);
            app.EditasCopyMenu.Text = 'Edit as Copy    ';
            app.SaveMenu = uimenu(app.ExperimentDesignerUIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save   ';
            app.HelpMenu = uimenu(app.ExperimentDesignerUIFigure);
            app.HelpMenu.Text = 'Help';
            app.MeasurementDesignerPanel = uipanel(app.ExperimentDesignerUIFigure);
            app.MeasurementDesignerPanel.Title = 'Measurement Designer';
            app.ExperimentLabel = uilabel(app.MeasurementDesignerPanel);
            app.ExperimentLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.MeasurementDesignerPanel, 'text');
            app.SubjectCodeEditFieldLabel = uilabel(app.MeasurementDesignerPanel);
            app.SubjectCodeEditFieldLabel.Text = 'Subject Code:';
            app.SubjectCodeEditField = uieditfield(app.MeasurementDesignerPanel, 'text');
            app.SessionEditFieldLabel = uilabel(app.MeasurementDesignerPanel);
            app.SessionEditFieldLabel.Text = 'Session:';
            app.SessionEditField = uieditfield(app.MeasurementDesignerPanel, 'text');
            app.MeasurementEditFieldLabel = uilabel(app.MeasurementDesignerPanel);
            app.MeasurementEditFieldLabel.Text = 'Measurement:';
            app.MeasurementEditField = uieditfield(app.MeasurementDesignerPanel, 'text');
            app.MethodsListBox = uilistbox(app.MeasurementDesignerPanel);
            app.MethodsListBox.Items = {'MEP Hotspot Search', 'Motor Thresholding', 'Stimulator Control', 'MEP Measurement', 'Input Output Curve (MEP)', 'Resting state EEG Anaysis', 'EEG triggered Peak Stimulation '};
            app.MethodsListBox.Value = 'Stimulator Control';
            app.MethodsLabel = uilabel(app.MeasurementDesignerPanel);
            app.MethodsLabel.Text = 'Methods';
            app.AddselectedmethodtoprotocolButton = uibutton(app.MeasurementDesignerPanel, 'push');
            app.AddselectedmethodtoprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @AddselectedmethodtoprotocolButtonPushed, true);
            app.AddselectedmethodtoprotocolButton.Text = 'Add selected method to protocol';
            app.ProtocolPanel = uipanel(app.ExperimentDesignerUIFigure);
            app.ProtocolPanel.Title = 'Protocol';
            app.ListBox = uilistbox(app.ProtocolPanel);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.RemovefromprotocolButton = uibutton(app.ProtocolPanel, 'push');
            app.RemovefromprotocolButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefromprotocolButtonPushed, true);
            app.RemovefromprotocolButton.Text = 'Remove from protocol';
            app.ClearallButton = uibutton(app.ProtocolPanel, 'push');
            app.ClearallButton.Text = 'Clear all';
            app.RUNButton = uibutton(app.ProtocolPanel, 'push');
            app.RUNButton.Text = 'RUN';
            app.ProtocolParametersPanel = uipanel(app.ExperimentDesignerUIFigure);
            app.ProtocolParametersPanel.Title = 'Protocol Parameters';
            app.NoMethodisselectedfromProtocolLabel = uilabel(app.ProtocolParametersPanel);
            app.NoMethodisselectedfromProtocolLabel.Text = 'No Method is selected from Protocol';
            app.InputOutputCurveMEPParametersPanel = uipanel(app.ExperimentDesignerUIFigure);
            app.InputOutputCurveMEPParametersPanel.Title = 'Input Output Curve (MEP) - Parameters Panel';
            app.MinimumStimulationIntensityEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.MinimumStimulationIntensityEditFieldLabel.Text = 'Minimum Stimulation Intensity';
            app.MinimumStimulationIntensityEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.MinimumStimulationIntensityEditField.Limits = [0 300];
            app.MinimumStimulationIntensityEditField.ValueDisplayFormat = '%.0f';
            app.StimulationIntensityUnitsLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.StimulationIntensityUnitsLabel.Text = 'Stimulation Intensity Units:';
            app.MaximumStimulationIntensityEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.MaximumStimulationIntensityEditFieldLabel.Text = 'Maximum Stimulation Intensity';
            app.MaximumStimulationIntensityEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.MaximumStimulationIntensityEditField.Limits = [0 300];
            app.MaximumStimulationIntensityEditField.ValueDisplayFormat = '%.0f';
            app.StepsizeofStimulationIntensitiesEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.StepsizeofStimulationIntensitiesEditFieldLabel.Text = 'Step size of Stimulation Intensities';
            app.StepsizeofStimulationIntensitiesEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.StepsizeofStimulationIntensitiesEditField.Limits = [0 Inf];
            app.StepsizeofStimulationIntensitiesEditField.ValueDisplayFormat = '%.0f';
            app.NooftrialsperStimulationIntensityEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.NooftrialsperStimulationIntensityEditFieldLabel.Text = 'No of trials per Stimulation Intensity';
            app.NooftrialsperStimulationIntensityEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.NooftrialsperStimulationIntensityEditField.Limits = [0 Inf];
            app.NooftrialsperStimulationIntensityEditField.ValueDisplayFormat = '%.0f';
            app.MinimumIntertrialIntervalMinITIEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.MinimumIntertrialIntervalMinITIEditFieldLabel.Text = 'Minimum Inter trial Interval (Min ITI)';
            app.MinimumIntertrialIntervalMinITIEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.MinimumIntertrialIntervalMinITIEditField.Limits = [0 Inf];
            app.MinimumIntertrialIntervalMinITIEditField.ValueDisplayFormat = '%.0f';
            app.MaximumIntertrialIntervalMaxITIEditFieldLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.MaximumIntertrialIntervalMaxITIEditFieldLabel.Text = 'Maximum Inter trial Interval (Max ITI)';
            app.MaximumIntertrialIntervalMaxITIEditField = uieditfield(app.InputOutputCurveMEPParametersPanel, 'numeric');
            app.MaximumIntertrialIntervalMaxITIEditField.Limits = [0 Inf];
            app.MaximumIntertrialIntervalMaxITIEditField.ValueDisplayFormat = '%.0f';
            app.StimulationParadigmDropDownLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.StimulationParadigmDropDownLabel.Text = 'Stimulation Paradigm';
            app.StimulationParadigmDropDown = uidropdown(app.InputOutputCurveMEPParametersPanel);
            app.StimulationParadigmDropDown.Items = {'Single Pulse', 'Paired Pulse', 'Brusts', 'Option 4'};
            app.StimulationParadigmDropDown.Value = 'Single Pulse';
            app.LiveMEPMeasurementPlotsCheckBox = uicheckbox(app.InputOutputCurveMEPParametersPanel);
            app.LiveMEPMeasurementPlotsCheckBox.Text = 'Live MEP Measurement Plots';
            app.LiveInputOutputCurvePlotsCheckBox = uicheckbox(app.InputOutputCurveMEPParametersPanel);
            app.LiveInputOutputCurvePlotsCheckBox.Text = 'Live Input Output Curve Plots';
            app.ButtonGroup = uibuttongroup(app.InputOutputCurveMEPParametersPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MSOButton = uiradiobutton(app.ButtonGroup);
            app.MSOButton.Text = '% MSO';
            app.MSOButton.Value = true;
            app.RMTButton = uiradiobutton(app.ButtonGroup);
            app.RMTButton.Text = '% RMT';
            app.RMTLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.RMTLabel.Text = '% RMT';
            app.RMTLabel_2 = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.RMTLabel_2.Text = '% RMT';
            app.RMTLabel_3 = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.RMTLabel_3.Text = '% RMT';
            app.msLabel = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.InputOutputCurveMEPParametersPanel);
            app.msLabel_2.Text = 'ms';
            app.StimIntenUnitsPanel = uipanel(app.InputOutputCurveMEPParametersPanel);
            app.MSOLabel_4 = uilabel(app.StimIntenUnitsPanel);
            app.MSOLabel_4.Text = '% MSO';
            app.MSOLabel_5 = uilabel(app.StimIntenUnitsPanel);
            app.MSOLabel_5.Text = '% MSO';
            app.MSOLabel_6 = uilabel(app.StimIntenUnitsPanel);
            app.MSOLabel_6.Text = '% MSO';
            app.msLabel_3 = uilabel(app.StimIntenUnitsPanel);
            app.msLabel_3.Text = 'ms';
            app.msLabel_4 = uilabel(app.StimIntenUnitsPanel);
            app.msLabel_4.Text = 'ms';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExperimentDesigner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExperimentDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExperimentDesignerUIFigure)
        end
    end
end