classdef besttoolboxapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BESTtoolboxappUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MotorThresholdHuntingTab        matlab.ui.container.Tab
        StatusEditField                 matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        CurrentTrialEditField           matlab.ui.control.NumericEditField
        CurrentTrialEditFieldLabel      matlab.ui.control.Label
        MEPAmpuVEditField               matlab.ui.control.NumericEditField
        MEPAmpuVEditFieldLabel          matlab.ui.control.Label
        NumberoftrialsLabel             matlab.ui.control.Label
        NumberoftrialsEditField         matlab.ui.control.EditField
        MEPthresholdleveluVEditField_2Label_2  matlab.ui.control.Label
        EMGchannelscountEditField       matlab.ui.control.EditField
        EMGchannelscountEditFieldLabel  matlab.ui.control.Label
        EEGchannelscountEditField       matlab.ui.control.EditField
        SimulationTimeEditField         slrealtime.ui.control.SimulationTimeEditField
        MEPthresholdleveluVEditField_2  matlab.ui.control.EditField
        EEGchannelscountEditFieldLabel  matlab.ui.control.Label
        MEPthresholdleveluVEditField_2Label  matlab.ui.control.Label
        StartintensityMSOEditField      matlab.ui.control.EditField
        ITIsjitterrangeEditField        matlab.ui.control.EditField
        StartintensityMSOEditFieldLabel  matlab.ui.control.Label
        ITIsjitterrangeEditFieldLabel   matlab.ui.control.Label
        ChannelLabel                    matlab.ui.control.Label
        COMportEditFieldLabel           matlab.ui.control.Label
        TargetEMGchannelEditField       matlab.ui.control.EditField
        COMportEditField                matlab.ui.control.EditField
        stopRMTButton                   matlab.ui.control.Button
        startRMTButton                  matlab.ui.control.Button
        ax_mep                          matlab.ui.control.UIAxes
        ax_trace                        matlab.ui.control.UIAxes
        DoseResponseCurveTab            matlab.ui.container.Tab
        StatusEditField_2               matlab.ui.control.EditField
        StatusEditField_2Label          matlab.ui.control.Label
        CurrentTrialEditField_2         matlab.ui.control.NumericEditField
        CurrentTrialEditField_2Label    matlab.ui.control.Label
        MEPAmpuVEditField_2             matlab.ui.control.NumericEditField
        MEPAmpuVEditField_2Label        matlab.ui.control.Label
        NumberoftrialsEditField_2Label  matlab.ui.control.Label
        NumberoftrialsEditField_2       matlab.ui.control.EditField
        MEPthresholdleveluVEditField_2Label_3  matlab.ui.control.Label
        EMGchannelscountEditField_2     matlab.ui.control.EditField
        EMGchannelscountEditField_2Label  matlab.ui.control.Label
        SimulationTimeEditField_2       slrealtime.ui.control.SimulationTimeEditField
        EEGchannelscountEditField_2     matlab.ui.control.EditField
        EEGchannelscountEditField_2Label  matlab.ui.control.Label
        StartintensityMSOEditField_2    matlab.ui.control.EditField
        StartintensityMSOEditField_2Label  matlab.ui.control.Label
        ITIsjitterrangeEditField_2      matlab.ui.control.EditField
        ITIsjitterrangeEditField_2Label  matlab.ui.control.Label
        TargetEMGchannelEditField_2Label  matlab.ui.control.Label
        TargetEMGchannelEditField_2     matlab.ui.control.EditField
        COMportEditField_2Label         matlab.ui.control.Label
        COMportEditField_2              matlab.ui.control.EditField
        stopDRCButton                   matlab.ui.control.Button
        startDRCButton                  matlab.ui.control.Button
        ax_drc_fit                      matlab.ui.control.UIAxes
        ax_drc_mep                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        stopmeasurement % Description

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.stopmeasurement=0;
            app.stopRMTButton.Enable='off';
            app.StatusEditField.Value='not started';
            version='2021bv4-2024';
            disp(version)

        end

        % Button pushed function: startRMTButton
        function startRMTButtonPushed(app, event)
            %% start the boss
            app.StatusEditField.Value='starting up...';
            app.startRMTButton.Enable='off';
            app.stopRMTButton.Enable='on';
            b=bossdevice;
            app.StatusEditField.Value='started bossdevice';
            % get all the parameters
            pause(20)
            app.StatusEditField.Value='wait time is over';
            comport=app.COMportEditField.Value
            eeg_channels=str2num(app.EEGchannelscountEditField.Value)
            aux_channels=str2num(app.EMGchannelscountEditField.Value)
            ChannelIdx=str2num(app.TargetEMGchannelEditField.Value)
            NumberOfTrials=str2num(app.NumberoftrialsEditField.Value)
            ITI=str2num(app.ITIsjitterrangeEditField.Value)
            StartingIntensity=str2num(app.StartintensityMSOEditField.Value)
            MEPThresholdLevelInMicroVolts=str2num(app.MEPthresholdleveluVEditField_2.Value)
            EMGDataStruct=[]
            delete(instrfind({'Port'},{'COM5'}));
            delete(instrfindall)
            m=magventure(comport)
            m.disconnect
            m.connect
            m.arm
            m.setAmplitude(round(StartingIntensity))
            pause(0.5)
            mep=zeros(1,NumberOfTrials);
            si=ones(1,NumberOfTrials)*StartingIntensity;
            app.StatusEditField.Value='connecting with stimulator';
            mrk=['mrk' num2str(1)];

            experimental_condition = [];
            experimental_condition{1}.name = 'random';
            experimental_condition{1}.phase_target = 0;
            experimental_condition{1}.phase_plusminus = pi;
            experimental_condition{1}.marker = 3;
            experimental_condition{1}.random_delay_range = 0.1;
            experimental_condition{1}.port = 1;
            obj.tc.(mrk).stimvalue = [StartingIntensity 1.0 1.00];
            obj.tc.(mrk).stepsize = [1 1 1];
            obj.tc.(mrk).minstep =  1;
            obj.tc.(mrk).maxstep =  8;
            obj.tc.(mrk).minvalue = 1;
            obj.tc.(mrk).maxvalue = 80;

            obj.tc.(mrk).responses = {[] [] []};
            obj.tc.(mrk).stimvalues = {[] [] []};  %storage for post-hoc review
            obj.tc.(mrk).stepsizes = {[] [] []};   %storage for post-hoc review
            obj.tc.(mrk).lastdouble = [0,0,0];
            obj.tc.(mrk).lastreverse = [0,0,0];


            app.StatusEditField.Value='in-progress';
            for i=1:NumberOfTrials
                app.CurrentTrialEditField.Value=i;
                if app.stopmeasurement==1
                    app.stopmeasurement=0;
                    break
                end


                m.fire;
                pause(0.3)

                mrk_data=[];aux_signal=[]
                defaultrun=Simulink.sdi.Run.getLatest
                mrk_signal= getSignalsByName(defaultrun,'mrk');
                aux_signal= getSignalsByName(defaultrun,'raw_aux_scope');
                mrk_data=squeeze(mrk_signal.Values.Data(end-15000:end));

                %plot(app.ax_mep,mrk_data)

                mrk_smpl=max(find(mrk_data>=65281));   %65283
                EMGDataStruct.EMGData=squeeze(aux_signal.Values.Data(ChannelIdx,:,end-15000:end));
                EMGDataStruct.EMGData=EMGDataStruct.EMGData(:,mrk_smpl-50:mrk_smpl+100)/100;
                EMGDataStruct.EMGChannel1=EMGDataStruct.EMGData(1,:)-mean(EMGDataStruct.EMGData(1,1:40));
                EMGDataStruct.EMGChannel2=EMGDataStruct.EMGData(2,:)-mean(EMGDataStruct.EMGData(2,1:40));
                EMGDataStruct.EMGChannel3=EMGDataStruct.EMGData(3,:)-mean(EMGDataStruct.EMGData(3,1:40));

                EMGDataStruct.EMGChannel1_AllTrials(i,:)=EMGDataStruct.EMGChannel1;
                EMGDataStruct.EMGChannel2_AllTrials(i,:)=EMGDataStruct.EMGChannel2;
                EMGDataStruct.EMGChannel3_AllTrials(i,:)=EMGDataStruct.EMGChannel3;

                mepepoch=EMGDataStruct.EMGChannel1;

                minn=min(mepepoch(70:100));
                maxx=max(mepepoch(70:100));
                mepp2pamp=abs(minn)+abs(maxx);
                mep(i)=mepp2pamp;


                StimDevice=1;
                stimtype = 1;
                experimental_condition{1}.port = 1;

                condition = experimental_condition{1};


                obj.tc.(mrk).stimvalues{StimDevice} = [obj.tc.(mrk).stimvalues{StimDevice}, round(obj.tc.(mrk).stimvalue(StimDevice),2)];
                obj.tc.(mrk).stepsizes{StimDevice} = [obj.tc.(mrk).stepsizes{StimDevice}, round(obj.tc.(mrk).stepsize(StimDevice),2)];


                if mepp2pamp >= MEPThresholdLevelInMicroVolts
                    disp('Hit')
                    answer = 1;
                else
                    disp('Miss')
                    answer = 0;
                end

                obj.tc.(mrk).responses{StimDevice} = [obj.tc.(mrk).responses{StimDevice}, answer];
                if obj.tc.(mrk).stepsize(StimDevice)<=-16
                    obj.tc.(mrk).stepsize(StimDevice)=-4;
                elseif obj.tc.(mrk).stepsize(StimDevice)>=16
                    obj.tc.(mrk).stepsize(StimDevice)=4;

                end


                if length(obj.tc.(mrk).responses{StimDevice}) == 1
                    if answer == 1
                        obj.tc.(mrk).stepsize(StimDevice) =  -obj.tc.(mrk).stepsize(StimDevice);
                    end
                elseif length(obj.tc.(mrk).responses{StimDevice}) == 2
                    if obj.tc.(mrk).responses{StimDevice}(end) ~= obj.tc.(mrk).responses{StimDevice}(end-1)
                        obj.tc.(mrk).stepsize(StimDevice) = -obj.tc.(mrk).stepsize(StimDevice)/2;
                        fprintf(' Step Reversed and Halved\n')
                        obj.tc.(mrk).lastreverse(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                    end

                elseif length(obj.tc.(mrk).responses{StimDevice})  == 3
                    if obj.tc.(mrk).responses{StimDevice}(end) ~= obj.tc.(mrk).responses{StimDevice}(end-1)
                        obj.tc.(mrk).stepsize(StimDevice) = -obj.tc.(mrk).stepsize(StimDevice)/2;
                        fprintf(' Step Reversed and Halved\n')
                        obj.tc.(mrk).lastreverse(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                    elseif obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-1) && obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-2)
                        obj.tc.(mrk).stepsize(StimDevice) = obj.tc.(mrk).stepsize(StimDevice)*2;
                        fprintf(' Step Size Doubled\n')
                        obj.tc.(mrk).lastdouble(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                    end

                elseif length(obj.tc.(mrk).responses{StimDevice}) > 3
                    if obj.tc.(mrk).responses{StimDevice}(end) ~= obj.tc.(mrk).responses{StimDevice}(end-1)
                        %             rule 1
                        obj.tc.(mrk).stepsize(StimDevice) = -obj.tc.(mrk).stepsize(StimDevice)/2;
                        fprintf(' Step Reversed and Halved\n')
                        obj.tc.(mrk).lastreverse(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                        %             rule 2 doesnt  need any specific dealing
                        %             rule 4
                    elseif obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-2) && obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-3)
                        obj.tc.(mrk).stepsize(StimDevice) = obj.tc.(mrk).stepsize(StimDevice)*2;
                        fprintf(' Step Size Doubled\n')
                        obj.tc.(mrk).lastdouble(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                        %             rule 3
                    elseif obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-1) && obj.tc.(mrk).responses{StimDevice}(end) == obj.tc.(mrk).responses{StimDevice}(end-2) && obj.tc.(mrk).lastdouble(StimDevice) ~= obj.tc.(mrk).lastreverse(StimDevice)-1
                        obj.tc.(mrk).stepsize(StimDevice) = obj.tc.(mrk).stepsize(StimDevice)*2;
                        fprintf(' Step Size Doubled\n')
                        obj.tc.(mrk).lastdouble(StimDevice) = length(obj.tc.(mrk).responses{StimDevice});
                    end

                end

                if abs(obj.tc.(mrk).stepsize(StimDevice)) < obj.tc.(mrk).minstep
                    if obj.tc.(mrk).stepsize(StimDevice) < 0
                        obj.tc.(mrk).stepsize(StimDevice) = -obj.tc.(mrk).minstep;
                    else
                        obj.tc.(mrk).stepsize(StimDevice) = obj.tc.(mrk).minstep;
                    end
                end

                obj.tc.(mrk).stimvalue(StimDevice) = obj.tc.(mrk).stimvalue(StimDevice) + obj.tc.(mrk).stepsize(StimDevice);

                if obj.tc.(mrk).stimvalue(StimDevice) < obj.tc.(mrk).minvalue
                    obj.tc.(mrk).stimvalue(StimDevice) = obj.tc.(mrk).minvalue;
                    disp('Minimum value reached.')
                end

                if obj.tc.(mrk).stimvalue(StimDevice) > obj.tc.(mrk).maxvalue
                    obj.tc.(mrk).stimvalue(StimDevice) = obj.tc.(mrk).maxvalue;
                    disp('Max value reached.')
                end

                %     si(i+1)=50%round(obj.tc.(mrk).stimvalue(StimDevice));
                si(i+1)=round(obj.tc.(mrk).stimvalue(StimDevice));
                m.setAmplitude(si(i+1));
%                 subplot(1,2,1)
%                 axes(app.ax_mep)
%                 title('MEP Threshold trace')
                plot(app.ax_trace,si(1:i),'LineWidth',3);
                xlim(app.ax_trace,[0 NumberOfTrials+2])
                set(app.ax_trace,'FontSize',15,'FontWeight','bold');
                set(app.ax_mep,'FontSize',15,'FontWeight','bold');
%                 ylabel('Stimulation Intensity (%MSO)')
%                 xlabel('No of trials')
                %     ylim([0 100])
%                 axes(app.ax_trace)
%                 subplot(1,2,2)
%                 cla
%                 title('MEPs')

                %     plot(mepepoch-mean(mepepoch(1:45)));
                plot(app.ax_mep,-50:1:100,mepepoch,'color','r','LineWidth',2);
                xlim(app.ax_mep,[-50 100])

                %     ylim([-1000 1000])
%                 ylabel('EMG (uV)')
%                 xlabel('Time (ms)')

                drawnow
                if app.stopmeasurement==1
                    app.stopmeasurement=0;
                    break
                end
                app.MEPAmpuVEditField.Value=double(round(mepp2pamp));

                pause(ITI(1)+rand*(diff(ITI)))
            end
            app.StatusEditField.Value='finished';

        end

        % Button pushed function: stopRMTButton
        function stopRMTButtonPushed(app, event)
                app.stopmeasurement=1;
                app.stopRMTButton.Enable='off';
                app.startRMTButton.Enable='on';
                app.StatusEditField.Value='stopped';
        end

        % Button pushed function: startDRCButton
        function startDRCButtonPushed(app, event)
            app.StatusEditField_2.Value='starting up...';
            app.startDRCButton.Enable='off';
            app.stopDRCButton.Enable='on';
            b=bossdevice;
            app.StatusEditField_2.Value='started bossdevice';
            % get all the parameters
            pause(20)
            app.StatusEditField_2.Value='wait time is over';
            comport=app.COMportEditField_2.Value
            eeg_channels=str2num(app.EEGchannelscountEditField_2.Value)
            aux_channels=str2num(app.EMGchannelscountEditField_2.Value)
            ChannelIdx=str2num(app.TargetEMGchannelEditField_2.Value)
            TPC=str2num(app.NumberoftrialsEditField_2.Value)
            ITI=str2num(app.ITIsjitterrangeEditField_2.Value)
            IntensitiesVector=str2num(app.StartintensityMSOEditField_2.Value)
            EMGDataStruct=[]
            
            delete(instrfind({'Port'},{'COM5'}));
            delete(instrfindall)
            m=magventure(comport)
            m.disconnect
            m.connect
            m.arm
            
            TrialStruct=[];
            for idx=1:TPC
                TrialStruct.ConditionOrder(idx,:)=randperm(numel(IntensitiesVector));
                TrialStruct.Intensities(idx,:)=IntensitiesVector(TrialStruct.ConditionOrder(idx,:));
                
            end
            TrialStruct.ConditionOrder=TrialStruct.ConditionOrder(:);
            TrialStruct.Intensities=TrialStruct.Intensities(:);
            TrialStruct.mepepoch=zeros(numel(TrialStruct.Intensities),151);
            app.StatusEditField_2.Value='dose-response curve in-progress';
            for i=1:numel(TrialStruct.Intensities)
                    app.CurrentTrialEditField_2.Value=i;
                if app.stopmeasurement==1
                    app.stopmeasurement=0;
                    break
                end

                m.setAmplitude(TrialStruct.Intensities(i))
                pause(0.5)
                m.fire;
                pause(0.3)
                mrk_data=[];aux_signal=[]
                defaultrun=Simulink.sdi.Run.getLatest
                mrk_signal= getSignalsByName(defaultrun,'mrk');
                aux_signal= getSignalsByName(defaultrun,'raw_aux_scope');
                mrk_data=squeeze(mrk_signal.Values.Data(end-15000:end));

                mrk_smpl=max(find(mrk_data>=65281))
                EMGDataStruct.EMGData=squeeze(aux_signal.Values.Data(ChannelIdx,:,end-15000:end));
                EMGDataStruct.EMGData=EMGDataStruct.EMGData(:,mrk_smpl-50:mrk_smpl+100)/100;
                EMGDataStruct.EMGChannel1=EMGDataStruct.EMGData(1,:)-mean(EMGDataStruct.EMGData(1,1:40));
                EMGDataStruct.EMGChannel2=EMGDataStruct.EMGData(2,:)-mean(EMGDataStruct.EMGData(2,1:40));
                EMGDataStruct.EMGChannel3=EMGDataStruct.EMGData(3,:)-mean(EMGDataStruct.EMGData(3,1:40));

                EMGDataStruct.EMGChannel1_AllTrials(i,:)=EMGDataStruct.EMGChannel1;
                EMGDataStruct.EMGChannel2_AllTrials(i,:)=EMGDataStruct.EMGChannel2;
                EMGDataStruct.EMGChannel3_AllTrials(i,:)=EMGDataStruct.EMGChannel3;

                mepepoch=EMGDataStruct.EMGChannel1;

                minn=min(mepepoch(70:100));
                maxx=max(mepepoch(70:100));
                mepp2pamp=abs(minn)+abs(maxx);
                TrialStruct.mep(i)=mepp2pamp;
                TrialStruct.mepepoch(i,:)=mepepoch;
                set(app.ax_drc_fit,'FontSize',15,'FontWeight','bold');
                set(app.ax_drc_mep,'FontSize',15,'FontWeight','bold');
                
                cla(app.ax_drc_mep)
                for iplot=1:numel(IntensitiesVector)
                    hold(app.ax_drc_mep, 'on');
                    plot(app.ax_drc_mep,-50:1:100,mean(TrialStruct.mepepoch(find(TrialStruct.ConditionOrder==iplot),:)),'LineWidth',2.5)
                end
                xlim(app.ax_drc_mep,[-50 100])
                app.MEPAmpuVEditField_2.Value=double(round(mepp2pamp));
                pause(ITI(1)+rand*(diff(ITI)))

            end
            app.StatusEditField_2.Value='finished';
        end

        % Button pushed function: stopDRCButton
        function stopDRCButtonPushed(app, event)
            app.stopmeasurement=1;
            app.stopDRCButton.Enable='off';
            app.startDRCButton.Enable='on';
            app.StatusEditField_2.Value='stopped';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BESTtoolboxappUIFigure = uifigure('Visible', 'off');
            app.BESTtoolboxappUIFigure.Name = 'BEST toolbox app';
            app.TabGroup = uitabgroup(app.BESTtoolboxappUIFigure);
            app.MotorThresholdHuntingTab = uitab(app.TabGroup);
            app.MotorThresholdHuntingTab.Title = 'Motor Threshold Hunting';
            app.ax_trace = uiaxes(app.MotorThresholdHuntingTab);
            title(app.ax_trace, 'motor threshold intensity trace')
            xlabel(app.ax_trace, 'no of trials')
            ylabel(app.ax_trace, '%MSO')
            zlabel(app.ax_trace, 'Z')
            app.ax_mep = uiaxes(app.MotorThresholdHuntingTab);
            title(app.ax_mep, 'motor evoked potential')
            xlabel(app.ax_mep, 'time (ms)')
            ylabel(app.ax_mep, 'micro volts')
            zlabel(app.ax_mep, 'Z')
            app.startRMTButton = uibutton(app.MotorThresholdHuntingTab, 'push');
            app.startRMTButton.ButtonPushedFcn = createCallbackFcn(app, @startRMTButtonPushed, true);
            app.startRMTButton.Text = 'start';
            app.stopRMTButton = uibutton(app.MotorThresholdHuntingTab, 'push');
            app.stopRMTButton.ButtonPushedFcn = createCallbackFcn(app, @stopRMTButtonPushed, true);
            app.stopRMTButton.Text = 'stop';
            app.COMportEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.COMportEditField.Value = 'COM5';
            app.TargetEMGchannelEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.TargetEMGchannelEditField.Value = '1';
            app.COMportEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.COMportEditFieldLabel.Text = 'COM port';
            app.ChannelLabel = uilabel(app.MotorThresholdHuntingTab);
            app.ChannelLabel.Text = {'Target EMG '; 'channel #'};
            app.ITIsjitterrangeEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.ITIsjitterrangeEditFieldLabel.Text = {'ITI (s)'; 'jitter range'};
            app.StartintensityMSOEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.StartintensityMSOEditFieldLabel.Text = {'Start intensity'; '(%MSO)'};
            app.ITIsjitterrangeEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.ITIsjitterrangeEditField.Value = '4 6';
            app.StartintensityMSOEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.StartintensityMSOEditField.Value = '50';
            app.MEPthresholdleveluVEditField_2Label = uilabel(app.MotorThresholdHuntingTab);
            app.MEPthresholdleveluVEditField_2Label.Text = {'MEP threshold'; 'level (uV)'};
            app.EEGchannelscountEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.EEGchannelscountEditFieldLabel.Text = {'EEG channels'; 'count'};
            app.MEPthresholdleveluVEditField_2 = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.MEPthresholdleveluVEditField_2.Value = '50';
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.MotorThresholdHuntingTab);
            app.EEGchannelscountEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.EEGchannelscountEditField.Value = '1';
            app.EMGchannelscountEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.EMGchannelscountEditFieldLabel.Text = {'EMG channels'; 'count'};
            app.EMGchannelscountEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.EMGchannelscountEditField.Value = '3';
            app.MEPthresholdleveluVEditField_2Label_2 = uilabel(app.MotorThresholdHuntingTab);
            app.MEPthresholdleveluVEditField_2Label_2.Text = {'bossdevice'; 'run timer'};
            app.NumberoftrialsEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.NumberoftrialsEditField.Value = '40';
            app.NumberoftrialsLabel = uilabel(app.MotorThresholdHuntingTab);
            app.NumberoftrialsLabel.Text = {'Number of '; 'trials'};
            app.MEPAmpuVEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.MEPAmpuVEditFieldLabel.Text = 'MEP Amp (uV)';
            app.MEPAmpuVEditField = uieditfield(app.MotorThresholdHuntingTab, 'numeric');
            app.CurrentTrialEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.CurrentTrialEditFieldLabel.Text = 'Current Trial';
            app.CurrentTrialEditField = uieditfield(app.MotorThresholdHuntingTab, 'numeric');
            app.StatusEditFieldLabel = uilabel(app.MotorThresholdHuntingTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.MotorThresholdHuntingTab, 'text');
            app.DoseResponseCurveTab = uitab(app.TabGroup);
            app.DoseResponseCurveTab.Title = 'Dose-Response Curve';
            app.ax_drc_mep = uiaxes(app.DoseResponseCurveTab);
            title(app.ax_drc_mep, 'MEPs')
            xlabel(app.ax_drc_mep, 'time (ms)')
            ylabel(app.ax_drc_mep, 'MEP amp. (uV)')
            zlabel(app.ax_drc_mep, 'Z')
            app.ax_drc_fit = uiaxes(app.DoseResponseCurveTab);
            title(app.ax_drc_fit, 'dose-response curve')
            xlabel(app.ax_drc_fit, 'stimulation intensities (%MSO)')
            ylabel(app.ax_drc_fit, 'MEP amp. (uV)')
            zlabel(app.ax_drc_fit, 'Z')
            app.startDRCButton = uibutton(app.DoseResponseCurveTab, 'push');
            app.startDRCButton.ButtonPushedFcn = createCallbackFcn(app, @startDRCButtonPushed, true);
            app.startDRCButton.Text = 'start';
            app.stopDRCButton = uibutton(app.DoseResponseCurveTab, 'push');
            app.stopDRCButton.ButtonPushedFcn = createCallbackFcn(app, @stopDRCButtonPushed, true);
            app.stopDRCButton.Text = 'stop';
            app.COMportEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.COMportEditField_2.Value = 'COM5';
            app.COMportEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.COMportEditField_2Label.Text = 'COM port';
            app.TargetEMGchannelEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.TargetEMGchannelEditField_2.Value = '1';
            app.TargetEMGchannelEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.TargetEMGchannelEditField_2Label.Text = {'Target EMG '; 'channel #'};
            app.ITIsjitterrangeEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.ITIsjitterrangeEditField_2Label.Text = {'ITI (s)'; 'jitter range'};
            app.ITIsjitterrangeEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.ITIsjitterrangeEditField_2.Value = '2 3';
            app.StartintensityMSOEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.StartintensityMSOEditField_2Label.Text = {'Start intensity'; '(%MSO)'};
            app.StartintensityMSOEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.StartintensityMSOEditField_2.Value = '50 60 70 80';
            app.EEGchannelscountEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.EEGchannelscountEditField_2Label.Text = {'EEG channels'; 'count'};
            app.EEGchannelscountEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.EEGchannelscountEditField_2.Value = '1';
            app.SimulationTimeEditField_2 = slrealtime.ui.control.SimulationTimeEditField(app.DoseResponseCurveTab);
            app.EMGchannelscountEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.EMGchannelscountEditField_2Label.Text = {'EMG channels'; 'count'};
            app.EMGchannelscountEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.EMGchannelscountEditField_2.Value = '3';
            app.MEPthresholdleveluVEditField_2Label_3 = uilabel(app.DoseResponseCurveTab);
            app.MEPthresholdleveluVEditField_2Label_3.Text = {'bossdevice'; 'run timer'};
            app.NumberoftrialsEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            app.NumberoftrialsEditField_2.Value = '2';
            app.NumberoftrialsEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.NumberoftrialsEditField_2Label.Text = {'Number of '; 'trials'};
            app.MEPAmpuVEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.MEPAmpuVEditField_2Label.Text = 'MEP Amp (uV)';
            app.MEPAmpuVEditField_2 = uieditfield(app.DoseResponseCurveTab, 'numeric');
            app.CurrentTrialEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.CurrentTrialEditField_2Label.Text = 'Current Trial';
            app.CurrentTrialEditField_2 = uieditfield(app.DoseResponseCurveTab, 'numeric');
            app.StatusEditField_2Label = uilabel(app.DoseResponseCurveTab);
            app.StatusEditField_2Label.Text = 'Status';
            app.StatusEditField_2 = uieditfield(app.DoseResponseCurveTab, 'text');
            % Show the figure after all components are created
            app.BESTtoolboxappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = besttoolboxapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BESTtoolboxappUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BESTtoolboxappUIFigure)
        end
    end
end